classdef viewMRE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MREViewerUIFigure           matlab.ui.Figure
        LoadPARRECDataButton        matlab.ui.control.Button
        magnitudeAxes               matlab.ui.control.UIAxes
        phaseAxes                   matlab.ui.control.UIAxes
        infoTable                   matlab.ui.control.Table
        timeAxes                    matlab.ui.control.UIAxes
        UnwrapandSmoothPhaseButton  matlab.ui.control.Button
        SliceSpinnerLabel           matlab.ui.control.Label
        SliceSpinner                matlab.ui.control.Spinner
        DirectionSpinnerLabel       matlab.ui.control.Label
        DirectionSpinner            matlab.ui.control.Spinner
        VibrationSpinnerLabel       matlab.ui.control.Label
        VibrationSpinner            matlab.ui.control.Spinner
        PhaseSpinnerLabel           matlab.ui.control.Label
        PhaseSpinner                matlab.ui.control.Spinner
        MREScanInfoLabel            matlab.ui.control.Label
        filenameLabel               matlab.ui.control.Label
        filenameEditField           matlab.ui.control.EditField
        IntrasliceMotionCorrectionButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        Data = struct; % Description
        header; % Description
        nSlices; % Description
        nVibs;
        nDirs;
        nPhases;
        
        currentSl;
        currentVib;
        currentDir;
        currentPhase;
        currentX;
        currentY;
        inplaneResolution; % Description
        inputDir % Description
        filename % Description
    end
    
    methods (Access = private)
        
        
        function updateTimeAxes(app)
            dat = squeeze(app.Data.phData(app.currentX,app.currentY,...
                app.currentSl,app.currentVib,app.currentDir,:));
            plot(app.timeAxes,dat,'-ok','LineWidth',2)
        end
        
        function updateFigureAxes(app)
            % to initialize images
            aa = squeeze(app.Data.magData(:,:,app.currentSl,app.currentVib,app.currentDir,app.currentPhase));
            aa = uint8(aa/max(aa(:))*255);
            overlay = zeros(size(app.Data.magData,1),size(app.Data.magData,2));
            overlay(app.currentX-1:app.currentX+1,app.currentY-1:app.currentY+1) = 1; overlay = imbinarize(overlay);
            oo = imoverlay(aa,overlay,'r');
            imshow(oo,[],'Parent',app.magnitudeAxes);
            
            aa = squeeze(app.Data.phData(:,:,app.currentSl,app.currentVib,app.currentDir,app.currentPhase));
            aa = aa - min(aa(:)); aa = uint8(aa/max(aa(:))*255);
            oo = imoverlay(aa,overlay,'r');
            imshow(oo,[],'Parent',app.phaseAxes);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadPARRECDataButton
        function LoadPARRECDataButtonPushed(app, event)
            addpath(sprintf('/home/%s/lood_storage/divi/Pub/Software/matlab/toolboxes-amc/parrec/',getenv('USER')));
            
            [filename,inputDir] = uigetfile('*.rec','Select Reconstructed Data(sets)');
            
            if ~iscell(filenames)
                count = 1;
            else
                count = cellfun(@(x) numel(x), filenames);
            end
            
            fName = fullfile(inputDir,filename);
            app.inputDir = inputDir;
            app.filename = filename;
            app.filenameEditField.Value = filename;
            
            % Read in the data and header
            [data, h] = readrec_V4_2(fName);
            app.inplaneResolution = h.pixdim;                         % in mm
            
            app.Data.magData = squeeze(data(:,:,:,:,:,1,:));
            tempPh = squeeze(data(:,:,:,:,:,2,:));
            tempPh = tempPh - min(tempPh(:)) - pi;
            app.Data.phData = tempPh;
            app.header = h;
            app.nSlices = size(data,3);
            app.nVibs = size(data,4);
            app.nDirs = size(data,5);
            app.nPhases = size(data,7);
            
            app.currentSl = round(app.nSlices/2);
            app.SliceSpinner.Limits = [1,app.nSlices];
            app.SliceSpinner.Value = app.currentSl;
            
            app.currentVib = 1;
            app.VibrationSpinner.Limits = [1,app.nVibs];
            app.VibrationSpinner.Value = app.currentVib;
            
            app.currentDir = 2;
            app.DirectionSpinner.Limits = [1,app.nDirs];
            app.DirectionSpinner.Value = app.currentDir;
            
            app.currentPhase = 1;
            app.PhaseSpinner.Limits = [1,app.nPhases];
            app.PhaseSpinner.Value = app.currentPhase;
            
            app.infoTable.Data = cat(2,app.nSlices,app.nDirs,app.nVibs,app.nPhases,round(app.inplaneResolution,2));
            app.infoTable.ColumnWidth = repmat({55},[1 7]);
            
            % initialize images
            app.currentX = round(app.header.nrows/2);
            app.currentX = round(app.header.ncols/2);
            updateFigureAxes(app);
            
        end

        % Window button down function: MREViewerUIFigure
        function MREViewerUIFigureWindowButtonDown(app, event)
            imSize = size(app.Data.magData,1);
            
            % get axis location in the magnitude axis
            xx = round(app.magnitudeAxes.CurrentPoint);
            % check that point makes sense
            x = xx(3); y = xx(1);
            if x > 0 && x < imSize && y > 0 && y < imSize
                app.currentX = x;
                app.currentY = y;
                updateTimeAxes(app);
                updateFigureAxes(app);
            else    % try to get points from phase axis instead
                % get axis location in the magnitude axis
                xx = round(app.phaseAxes.CurrentPoint);
                % check that point makes sense
                x = xx(3); y = xx(1);
                if x > 0 && x < imSize && y > 0 && y < imSize
                    app.currentX = x;
                    app.currentY = y;
                    updateTimeAxes(app);
                    updateFigureAxes(app);
                end
            end
        end

        % Value changing function: SliceSpinner
        function SliceSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.currentSl = changingValue;
            updateTimeAxes(app);
            updateFigureAxes(app);
        end

        % Value changing function: DirectionSpinner
        function DirectionSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.currentDir = changingValue;
            updateTimeAxes(app);
            updateFigureAxes(app);
        end

        % Value changing function: VibrationSpinner
        function VibrationSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.currentVib = changingValue;
            updateTimeAxes(app);
            updateFigureAxes(app);
        end

        % Value changing function: PhaseSpinner
        function PhaseSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.currentPhase = changingValue;
            updateFigureAxes(app);
        end

        % Button pushed function: UnwrapandSmoothPhaseButton
        function UnwrapandSmoothPhaseButtonPushed(app, event)
            addpath(sprintf('/home/%s/lood_storage/divi/Projects/asap/incoming/parrec/MATLAB_Scripts/tempApp/unwrap/',getenv('USER')));
            % perform unwrapping over all directions/vibrations
            h = waitbar(0, sprintf('Unwrapping and smoothing all directions and vibrations...'));
            count = 0;
            for vib = 1:app.nVibs
                for Dir = 1:app.nDirs
                    phi = squeeze(app.Data.phData(:,:,:,vib,Dir,:));
                    phi_unwrapped = phi + 2*pi .* double(unwrap_4D(phi));
                    
                    % perform 3d smoothing of the phase over each wave
                    % phase
                    for ph = 1:app.nPhases
                        % create complex signal, smooth and extract the phase
                        tempPhase = exp(1i*phi_unwrapped(:,:,:,ph));
                        
                        % default smoothing parameters from MDEV script
                        parameters.filter = 'gaussian';
                        parameters.size = [5 5 1]; %[pixel]
                        parameters.sd = 0.65; %[pixel] standard deviation
                        if app.nSlices > 1
                            temp = smooth3(tempPhase, parameters.filter, parameters.size, parameters.sd);
                        else % nSlice == 1
                            tempPhase = repmat(tempPhase,[1 1 2]);
                            temp = smooth3(tempPhase, parameters.filter, parameters.size, parameters.sd);
                            temp = temp(:,:,1);
                        end
                        phi_unwrapped(:,:,:,ph) = angle(temp);
                    end
                    app.Data.phData(:,:,:,vib,Dir,:) = permute(phi_unwrapped, [1 2 3 5 6 4]);
                    count = count+1;
                    
                    waitbar (count/(app.nVibs*app.nDirs), h)
                end
            end
            close(h);
            
            updateFigureAxes(app);
            updateTimeAxes(app);
        end

        % Button pushed function: IntrasliceMotionCorrectionButton
        function IntrasliceMotionCorrectionButtonPushed(app, event)
            %         addpath(sprintf('/home/%s/lood_storage/divi/Projects/asap/incoming/parrec/MATLAB_Scripts/tempApp/ElastixFromMatlab/',getenv('USER')));
            
            % grab one slice, then concatenate phases, followed by
            % directions, followed by vibrations
            count = 0; total = app.nSlices*app.nPhases*app.nDirs*app.nVibs;
            h = waitbar(0, sprintf('Performing intraslice motion correction...'));
            for sl = 1:app.nSlices
                
                mag = squeeze(app.Data.magData(:,:,sl,:,:,:));
                % and phase data
                ph = squeeze(app.Data.phData(:,:,sl,:,:,:));
                
                % create N x M x frames slice
                mag = permute(mag,[1 2 5 4 3]);
                mag = reshape(mag,[size(mag,1),size(mag,2),app.nPhases*app.nDirs*app.nVibs]);
                %                 mag = mag/max(mag(:));
                ph = permute(ph,[1 2 5 4 3]);
                ph = reshape(ph,[size(ph,1),size(ph,2),app.nPhases*app.nDirs*app.nVibs]);
                
                % the 'target' image
                target = mean(mag,3); target = target/max(target(:));
                
                %                 % elastix parameters
                %                 myConf = elxDefaultConfiguration;
                %                 [Param,ImageTarget,ImageMoving,Mask] = mydefaultparameters(size(target,1));
                %                 ImageTarget.Data = int16(target);
                %                 Mask.Data=zeros(size(target,1),size(target,2),'int16');
                %                 idx = find(target);
                %                 Mask.Data(idx) = 1;
                
                % loop over all concatenated frames
                for frame = 1:size(mag,3)
                    MOVING = mag(:,:,frame); MOVING = MOVING/max(MOVING(:));
                    phMoving = ph(:,:,frame);
                    [magOut,phOut,T] = registerImages(MOVING,target,phMoving);
                    regMag(:,:,frame) = magOut;
                    regPh(:,:,frame) = phOut;
                    %                     [StrDataxRegMoving, Transforms, Log, Success, Message] = elxElastix(myConf,Param, ImageTarget, ImageMoving, 'FixedMask', Mask);
                    transforms(:,:,frame) = T;
                    count = count+1;
                    if mod(total,count) == 4
                        waitbar (count/total, h)
                    end
                end
                
                regMag = reshape(regMag,[size(regMag,1),size(regMag,2),app.nPhases,app.nDirs,app.nVibs]);
                regMag = permute(regMag,[1 2 6 5 4 3]);
                regPh = reshape(regPh,[size(regPh,1),size(regPh,2),app.nPhases,app.nDirs,app.nVibs]);
                regPh = permute(regPh,[1 2 6 5 4 3]);
                app.Data.magData(:,:,sl,:,:,:) = regMag;
                app.Data.magPh(:,:,sl,:,:,:) = regPh;
                clear regMag regPh
                
                % optional plot resolved translations
                figure(100); clf;
                plot(app.inplaneResolution(1)*squeeze(transforms(3,1,:)),'k');hold on;
                plot(app.inplaneResolution(2)*squeeze(transforms(3,2,:)),'b');
                ylim([-app.inplaneResolution(1) app.inplaneResolution(1)])
                legend('x','y'); ylabel('displacement (mm)'); xlabel('frame number')
                title(['slice #' num2str(sl)])
                pause(0.1)
            end
            close(h);
            
            updateFigureAxes(app);
            updateTimeAxes(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MREViewerUIFigure = uifigure('Visible', 'off');
            app.MREViewerUIFigure.Name = 'MRE Viewer';
            app.MREViewerUIFigure.WindowButtonDownFcn = createCallbackFcn(app, @MREViewerUIFigureWindowButtonDown, true);
            app.LoadPARRECDataButton = uibutton(app.MREViewerUIFigure, 'push');
            app.LoadPARRECDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPARRECDataButtonPushed, true);
            app.LoadPARRECDataButton.Text = 'Load PARREC Data';
            app.magnitudeAxes = uiaxes(app.MREViewerUIFigure);
            title(app.magnitudeAxes, 'Magnitude Image')
            xlabel(app.magnitudeAxes, '')
            ylabel(app.magnitudeAxes, '')
            app.magnitudeAxes.XTick = [];
            app.magnitudeAxes.YTick = [];
            app.phaseAxes = uiaxes(app.MREViewerUIFigure);
            title(app.phaseAxes, 'Phase Image')
            xlabel(app.phaseAxes, '')
            ylabel(app.phaseAxes, '')
            app.phaseAxes.XTick = [];
            app.phaseAxes.YTick = [];
            app.infoTable = uitable(app.MREViewerUIFigure);
            app.infoTable.ColumnName = {'Slices'; 'Directions'; 'Vibrations'; 'Phases'; 'ResX'; 'ResY'; 'ResZ'};
            app.infoTable.RowName = {};
            app.timeAxes = uiaxes(app.MREViewerUIFigure);
            title(app.timeAxes, 'Time steps')
            xlabel(app.timeAxes, 'phase number')
            ylabel(app.timeAxes, '')
            app.UnwrapandSmoothPhaseButton = uibutton(app.MREViewerUIFigure, 'push');
            app.UnwrapandSmoothPhaseButton.ButtonPushedFcn = createCallbackFcn(app, @UnwrapandSmoothPhaseButtonPushed, true);
            app.UnwrapandSmoothPhaseButton.Text = 'Unwrap and Smooth Phase';
            app.SliceSpinnerLabel = uilabel(app.MREViewerUIFigure);
            app.SliceSpinnerLabel.Text = 'Slice';
            app.SliceSpinner = uispinner(app.MREViewerUIFigure);
            app.SliceSpinner.ValueChangingFcn = createCallbackFcn(app, @SliceSpinnerValueChanging, true);
            app.SliceSpinner.Value = 1;
            app.DirectionSpinnerLabel = uilabel(app.MREViewerUIFigure);
            app.DirectionSpinnerLabel.Text = 'Direction';
            app.DirectionSpinner = uispinner(app.MREViewerUIFigure);
            app.DirectionSpinner.ValueChangingFcn = createCallbackFcn(app, @DirectionSpinnerValueChanging, true);
            app.DirectionSpinner.Value = 1;
            app.VibrationSpinnerLabel = uilabel(app.MREViewerUIFigure);
            app.VibrationSpinnerLabel.Text = {'Vibration'; ''};
            app.VibrationSpinner = uispinner(app.MREViewerUIFigure);
            app.VibrationSpinner.ValueChangingFcn = createCallbackFcn(app, @VibrationSpinnerValueChanging, true);
            app.VibrationSpinner.Value = 1;
            app.PhaseSpinnerLabel = uilabel(app.MREViewerUIFigure);
            app.PhaseSpinnerLabel.Text = {'Phase'; ''};
            app.PhaseSpinner = uispinner(app.MREViewerUIFigure);
            app.PhaseSpinner.ValueChangingFcn = createCallbackFcn(app, @PhaseSpinnerValueChanging, true);
            app.PhaseSpinner.Value = 1;
            app.MREScanInfoLabel = uilabel(app.MREViewerUIFigure);
            app.MREScanInfoLabel.Text = 'MRE Scan Info ';
            app.filenameLabel = uilabel(app.MREViewerUIFigure);
            app.filenameLabel.Text = 'filename:';
            app.filenameEditField = uieditfield(app.MREViewerUIFigure, 'text');
            app.filenameEditField.Editable = 'off';
            app.IntrasliceMotionCorrectionButton = uibutton(app.MREViewerUIFigure, 'push');
            app.IntrasliceMotionCorrectionButton.ButtonPushedFcn = createCallbackFcn(app, @IntrasliceMotionCorrectionButtonPushed, true);
            app.IntrasliceMotionCorrectionButton.Text = 'Intraslice Motion Correction';
            % Show the figure after all components are created
            app.MREViewerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = viewMRE

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MREViewerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MREViewerUIFigure)
        end
    end
end